classdef RipAnalysis < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                     matlab.ui.Figure
    yaxisButtonGroup             matlab.ui.container.ButtonGroup
    TButton                      matlab.ui.control.RadioButton
    xButton                      matlab.ui.control.RadioButton
    fButton                      matlab.ui.control.RadioButton
    SelectintervaloptionalLabel  matlab.ui.control.Label
    EndButton                    matlab.ui.control.Button
    StartButton                  matlab.ui.control.Button
    xaxisButtonGroup             matlab.ui.container.ButtonGroup
    RecordButton                 matlab.ui.control.RadioButton
    TimesButton                  matlab.ui.control.RadioButton
    AddripzipButton              matlab.ui.control.Button
    OutputfolderTextArea         matlab.ui.control.TextArea
    OutputfolderTextAreaLabel    matlab.ui.control.Label
    SelectfolderButton_2         matlab.ui.control.Button
    SaveButton                   matlab.ui.control.Button
    RecallsavedchangesButton     matlab.ui.control.Button
    RemoveripszipsButton         matlab.ui.control.Button
    InspectripzipButton          matlab.ui.control.Button
    DatafileTextArea             matlab.ui.control.TextArea
    DatafileTextAreaLabel        matlab.ui.control.Label
    DatafolderTextArea           matlab.ui.control.TextArea
    DatafolderTextAreaLabel      matlab.ui.control.Label
    RestoreviewButton            matlab.ui.control.Button
    SelectfileButton             matlab.ui.control.Button
    SelectfolderButton           matlab.ui.control.Button
    UIAxes2                      matlab.ui.control.UIAxes
    UIAxes                       matlab.ui.control.UIAxes
  end


  properties (Access = private)
    Datafolder % Current folder path for experiment data files
    Datafile % Experiment data file name
    traces % arrays of pull and relax traces
    ncycles % Current number of cycles
    cycleno % Index of current cycle in traces.pull and traces.relax
    h % struct of graphics handles to the current cycle plot
    settings % Copy of settings struct in RipAnalysis_settings
    filedata % Description
    h2 %struct of graphics handles to the current file plot
    EditTable % Local vesrsion of EditTableApp
    par % Parameter struct
    goodtraces % ncycles bu 2 logical array. app.goodtraces(i,1) = true
    % if a rip is identified in cycle i
    Outputfolder % Folder for results Matlab tables
    hselect % Description
  end

  methods (Access = public)

  end

  methods (Access = private)

    function reset_axis_limits(app,event)
      if strcmp(event.Source.Tag,'file')  % Upper axes (app.UIAxes2)
        Xlimits = [min(app.filedata.t),max(app.filedata.t)];
        if app.fButton.Value
          Ylimits = [min(app.filedata.f),max(app.filedata.f)];
        else
          Ylimits = [min(app.filedata.x),max(app.filedata.x)];
        end
        set(app.UIAxes2,'Xlim',Xlimits);
        set(app.UIAxes2,'Ylim',Ylimits);
      else  % Lower axes
        n = app.CyclenoSpinner.Value;
        Xlimits = [min(app.traces.pull(n).x),max(app.traces.pull(n).x)];
        Ylimits = [min(app.traces.pull(n).f),max(app.traces.pull(n).f)];
        set(app.UIAxes,'Xlim',Xlimits);
        set(app.UIAxes,'Ylim',Ylimits);
      end
    end


    function select_Tracesfolder(app)
      % Called from TracesfolderMenuSelected, but also from others if
      % Tracesfolder has not been defined yet.
      waitfor(msgbox('Please select a folder for traces files'));
      folder = uigetdir;
      if folder == 0
        return   % User pressed "Cancel"
      end
      app.settings.Tracesfolder = folder;
      appsettings = app.settings;
      save RipAnalysis_settings appsettings
    end

    function read_experiment(app)
      file_full = strrep(fullfile(app.Datafolder,app.Datafile),'\','/');
      [~,~,pull,relax,t,f,x,T,peaks,valleys] = analyse_experiment(file_full);
      app.filedata.t = t;
      app.filedata.f = f;
      app.filedata.x = x;
      app.filedata.T = T;
      app.filedata.recordno = (1:numel(t))';
      app.filedata.peaks = peaks;
      app.filedata.valleys = valleys;
      app.filedata.limits = [1,numel(t)];
      app.traces.pull = pull;
      app.traces.relax = relax;

      plot_file(app)
    end

    function store_traces(app)
      % Store current state of traces for all cycles in TracesFolder
      % Called from StoretracesButtonPushed, but also from update_structs
      % in order to store modified rip labels
      if ~isfield(app.settings,'Tracesfolder')
        select_Tracesfolder(app);
      end
      matfile = strrep(app.Datafile,'.txt','.mat');
      pull = app.traces.pull;
      relax = app.traces.relax;
      save(strrep(fullfile(app.settings.Tracesfolder,matfile),'\','/'),'pull','relax');
    end

    function plot_file(app)
      % Plots force or extent vs time in the upper axes

      % initialise variables
      riptime = [];
      ripforce = [];
      riprecord = [];
      ziptime = [];
      zipforce = [];
      ziprecord = [];
      if ~isempty(app.traces.pull)
        riptime = vertcat(app.traces.pull.time);
        ripforce = vertcat(app.traces.pull.force);
        riprecord = find(ismember(app.filedata.t,riptime));
      end
      if ~isempty(app.traces.relax)
        ziptime = vertcat(app.traces.relax.time);
        zipforce = vertcat(app.traces.relax.force);
        ziprecord = find(ismember(app.filedata.t,ziptime));
      end
      xrange = app.filedata.limits(1):app.filedata.limits(2);
      if app.fButton.Value
        yvar = app.filedata.f(xrange);
        ylbl = 'pN';
      elseif app.xButton.Value
        yvar = app.filedata.x(xrange);
        ylbl = 'nm';
      else
        yvar = app.filedata.T;
        ylbl = '°C';
      end
      if app.TimesButton.Value
        xvar = app.filedata.t(xrange);
        xrip = riptime;
        xzip = ziptime;
        xlbl = 'Timestamp (s)';
      else
        xvar = app.filedata.recordno(xrange);
        xrip = riprecord;
        xzip = ziprecord;
        xlbl = 'Record no';
      end
      y1 = min(yvar);
      y2 = max(yvar);
      yrange = y2-y1;
      y1 = y1 - 0.05*max(yrange,1);
      y2 = y2 + 0.05*max(yrange,1);
      hold(app.UIAxes,"off")
      xlim(app.UIAxes,[xvar(1),xvar(end)]);
      ylim(app.UIAxes,[y1,y2]);
      app.h2.val = plot(app.UIAxes,xvar,yvar);
      if app.fButton.Value
        hold(app.UIAxes,"on")
        plot(app.UIAxes,xrip,ripforce,'*r');
        plot(app.UIAxes,xzip,zipforce,'ok','MarkerFaceColor',[1 1 1]);
      end
      xlim(app.UIAxes,[xvar(1),xvar(end)]);
      title(app.UIAxes,app.Datafile,'interpreter','none');
      xlabel(app.UIAxes,xlbl);
      ylabel(app.UIAxes,ylbl);
      box(app.UIAxes,'on');
      zoom(app.UIAxes,'on');
      app.UIAxes2.Toolbar.Visible = "off";
      app.xaxisButtonGroup.Visible = "on";
    end

    function file = matfilename(app)
      % Create filename for the uutput table mat file
      slashes = strfind(app.Datafolder,'/');
      part1 = extractAfter(app.Datafolder,slashes(end));
      part2 = strrep(app.Datafile,'.txt','.mat');
      file = strcat(part1,"_",part2);
    end


    function [localrips,localzips,visiblerips,visiblezips] = selectrip(app,mode)
      % creates a listdlg showing all rips and zips in view
      % Returns the rip and zip numbers in the visible part of the plot
      % and the tables converting local numbering to global
      %
      % mode = 'Single' or 'Multiple' (default)
      if nargin < 2
        mode = 'Multiple';  % allow selection of multiple rips
      end
      riptimes = vertcat(app.traces.pull.time);
      ziptimes = vertcat(app.traces.relax.time);
      xrng = xlim(app.UIAxes);
      if app.TimesButton.Value
        visiblerips = find(riptimes>=xrng(1) & riptimes <= xrng(2));
        visiblezips = find(ziptimes>=xrng(1) & ziptimes <= xrng(2));
      else
        riprecords = find(ismember(app.filedata.t,riptimes));
        ziprecords = find(ismember(app.filedata.t,ziptimes));
        visiblerips = find(riprecords>=xrng(1) & riprecords <= xrng(2));
        visiblezips = find(ziprecords>=xrng(1) & ziprecords <= xrng(2));
      end
      nrip = numel(visiblerips);
      nzip = numel(visiblezips);
      if nrip + nzip < 1
        msgbox('No rips or zips visible. Please pan and zoome to another range');
        return
      elseif nrip + nzip > 20
        msgbox('More than 20 rips or zips visible. Please zoom to a smaller range');
        return
      end
      ripforces = vertcat(app.traces.pull.force);
      zipforces = vertcat(app.traces.relax.force);
      % Number the visible rips and zips, starting at 1 and collect names
      % in riplist
      riplist = strings(nrip+nzip,1);
      for i = 1:nrip
        k = visiblerips(i);
        text(app.UIAxes,riptimes(k),ripforces(k)+1,num2str(i));
        riplist(i) = strcat("rip",num2str(i));
      end
      for i = 1:nzip
        k = visiblezips(i);
        text(app.UIAxes,ziptimes(k),zipforces(k)+1,num2str(i));
        riplist(i+nrip) = strcat("zip",num2str(i));
      end
      [indx,~] = listdlg('promptstring','Select rip or zip to modify', ...
        'ListString',riplist,'SelectionMode',mode);
      localrips = indx(indx<=nrip);
      localzips = indx(indx>nrip)-nrip;
    end

    function [trace,neighbour] = nexttrace(app,trace)
      % Range of next trace after trace, wether or not it has a rip/zip
      t0 = trace.time;
      peaks = app.filedata.peaks;
      valleys = app.filedata.valleys;
      relax = app.traces.relax;
      pull = app.traces.pull;
      t = app.filedata.t;
      nextpeak = find(t(peaks)>t0,1);
      nextvalley = find(t(valleys)>t0,1);
      sgn = sign(trace.f(end)-trace.f(1));
      neighbour = [];
      if sgn > 0  % pulling trace, trace is a rip struct
        nextzip = find(vertcat(relax.time)>t0,1);
        if ~isempty(nextzip) & relax(nextzip).time < t(valleys(nextvalley))
          neighbour = relax(nextzip);
        else  % No zip in next ttace
          nextrange = peaks(nextpeak):valleys(nextvalley);
          neighbour.t = t(nextrange);
          neighbour.f = app.filedata.f(nextrange);
          neighbour.x = app.filedata.x(nextrange);
        end
        % shift and scale the x values
        [trace,neighbour] = adjust_x(trace,neighbour);
      else  % Relaxing trace, trace is a zip struct
        nextrip = find(vertcat(pull.time)>t0,1);
        if ~isempty(nextrip) & pull(nextrip).time < t(peaks(nextpeak))
          neighbour = pull(nextrip);
        else % No rip in next relax trace
          nextrange = valleys(nextvalley):peaks(nextpeak);
          neighbour.t = t(nextrange);
          neighbour.f = app.filedata.f(nextrange);
          neighbour.x = app.filedata.x(nextrange);
        end
        [trace,neighbour] = adjust_x(trace,neighbour);
      end
    end

    function setlimit(app,type)
      % ***********************************************
      % Cast magic spell to make ginput crosshairs apppear in App window:
      fhv = app.UIFigure.HandleVisibility;        % Current state
      app.UIFigure.HandleVisibility = 'callback'; % Temporarily make UIFigure handle visible outside app
      set(0, 'CurrentFigure', app.UIFigure);
      answer = 'No';
      [x,~] = ginput(1);
      h = xline(app.UIAxes,x);
      answer = questdlg("OK?");
      while strcmp(answer,'No')
        answer = msgbox;
        delete(h);
      end
      app.UIFigure.HandleVisibility = fhv;
      % Magic spell lifted
      % ***********************************************
      if ~strcmp(answer,'Yes')
        return
      end
      xline(app.UIAxes,x);
      if app.RecordButton.Value
        r = x;
      else
        % r = find(app.filedata.t==x);
        [~,r] = min(abs(app.filedata.t-x));
      end

      switch type
        case 'Start'
          app.filedata.limits(1) = r;
        case 'End'
          app.filedata.limits(2) = r;
      end
      plot_file(app)
    end

    function [answer,new,sgn] = tryrip(app)
      % Called by AddripButtonPushed to try out adding a rip
      zoom(app.UIAxes,'on');
      % msg = 'To zoom or pan: move message box outside axes. Click OK when ready.';
      % waitfor(msgbox(msg));
      new = [];
      [rip,~] = ginput(1);
      t = app.filedata.t;
      f = app.filedata.f;
      x = app.filedata.x;
      if app.TimesButton.Value
        ix = find(t>rip,1);
      else
        ix = rip;
      end
      % ix is the index in t,x anf f correspondig to the selected point

      lastpeak = find(app.filedata.peaks < ix,1,'last');
      lastpeakpos = app.filedata.peaks(lastpeak);
      lastvalley = find(app.filedata.valleys < ix,1,'last');
      lastvalleypos = app.filedata.valleys(lastvalley);
      if lastpeakpos > lastvalleypos  % pulling trace
        tracerng = lastpeakpos:app.filedata.valleys(lastvalley+1);
      else
        tracerng = lastvalleypos:app.filedata.peaks(lastpeak+1);
      end
      sgn = sign(lastvalleypos-lastpeakpos);  % 1: pull. -1: relax
      rng = tracerng(valid_trace_part(f(tracerng),sgn));
      % rng is the index range for the valid part of the trace around ix
      if ~ismember(ix,rng)  % ix is not inside a valid trace part
        answer = "No";
        return
      end
      % fitting range before rip/zip
      nf = numel(rng);
      fitb_start = max(ix - round(app.par.maxfitfraction*nf),rng(1));
      fitrange_b = fitb_start:ix;
      % fitting range after rip/zip
      fita_end = min(ix+2*app.par.ripsteps+round(app.par.maxfitfraction*nf),rng(end));
      fitrange_a = (ix + +2*app.par.ripsteps):fita_end;
      % Fit straight lines before and after rip/zip:
      pft_b = polyfit(t(fitrange_b),f(fitrange_b),1);
      pft_a = polyfit(t(fitrange_a),f(fitrange_a),1);
      if app.TimesButton.Value
        htry(1) = plot(app.UIAxes,t(fitrange_b),polyval(pft_b,t(fitrange_b)),'k');
        htry(2) = plot(app.UIAxes,t(fitrange_a),polyval(pft_a,t(fitrange_a)),'k');
        htry(3) = plot(app.UIAxes,t(ix),polyval(pft_b,t(ix)),'*r');
      else
        pf_b = polyfit(fitrange_b,f(fitrange_b),1);
        pf_a = polyfit(fitrange_a,f(fitrange_a),1);
        htry(1) = plot(app.UIAxes,fitrange_b,polyval(pf_b,fitrange_b),'k');
        htry(2) = plot(app.UIAxes,fitrange_a,polyval(pf_a,fitrange_a),'k');
        htry(3) = plot(app.UIAxes,ix,polyval(pf_b,ix),'*r');
      end
      answer = questdlg('Satisfied?');
      if ~strcmp(answer,'Yes')
        delete(htry);
        return
      end
      pfx_b = polyfit(x(fitrange_b),f(fitrange_b),1);
      pfx_a = polyfit(x(fitrange_a),f(fitrange_a),1);
      % Create new rip/zip struct
      new.t = t(rng);
      new.x = x(rng);
      new.f = f(rng);
      new.T = app.filedata.T(rng);
      new.file = fullfile(app.Datafolder,app.Datafile);
      new.ripx = x(ix);
      new.force = polyval(pft_b,t(ix));
      new.fstep = new.force-polyval(pft_a,t(ix));
      xend = (new.force-pfx_a(2))/pfx_a(1);
      new.deltax = xend - new.ripx;
      new.time = t(ix);
      new.fdot = pft_b(1);
      new.slope = pfx_b(1);
      new.rip_index = ix - rng(1) +1;
      new.pfx_b = pfx_b;
      new.pfx_a = pfx_a;
      new.dt = mean(diff(new.t));
      new.temperature = app.filedata.T(ix);
      new.noise = std(f(fitrange_b)-polyval(pft_b,t(fitrange_b)));
      new.pullingspeed = median(diff(new.x)./diff(new.t));
    end

    function s = table2tracestruct(app,T,row,sgn)
      % Create a trace struct for the current file from a results table row
      if row == 0  % Create empty struct with all fields
        s.file = [];
        s.t = [];
        s.f = [];
        s.x = [];
        s.T = [];
        s.ripx   = [];
        s.force  = [];
        s.fstep  = [];
        s.deltax = [];
        s.time   = [];
        s.fdot   = [];
        s.slope  = [];
        s.rip_index = [];
        s.dt     = [];
        s.temperature = [];
        s.noise  = [];
        s.pullingspeed = [];
        s.pfx_b = [];
        s.pfx_a = [];
        return
      end

      s.file = fullfile(app.Datafolder,app.Datafile);
      shortname = shorten_filename(s.file,2);
      if ~isequal(T.Filename(row),shortname)
        warning('Results file does not match the current file')
      end

      ix = find(app.filedata.t == T.Time(row));
      if sgn > 0
        trace_start = app.filedata.valleys(find(app.filedata.valleys<ix,1,'last'));
        trace_stop = app.filedata.peaks(find(app.filedata.peaks>ix,1));
      else
        trace_start = app.filedata.peaks(find(app.filedata.peaks<ix,1,'last'));
        trace_stop = app.filedata.valleys(find(app.filedata.valleys>ix,1));
      end
      range = valid_trace_part(app.filedata.f(trace_start:trace_stop),sgn) ...
        + trace_start -1;
      s.t = app.filedata.t(range);
      s.f = app.filedata.f(range);
      s.x = app.filedata.x(range);
      s.T = app.filedata.T(range);

      s.ripx = T.Trapx(row);
      s.force = T.Force(row);
      s.fstep = T.Forceshift(row);
      s.deltax = T.Deltax(row);
      s.time = T.Time(row);
      s.fdot = T.Fdot(row);
      s.slope = T.Slope(row);
      s.rip_index = ix - range(1) +1;
      s.dt = T.Timestep(row);
      s.temperature = T.Temperature(row);
      s.noise = T.Noise(row);
      s.pullingspeed = T.Pullingspeed(row);

      % fitting range before rip/zip
      nf = numel(range);
      fitb_start = max(ix - round(app.par.maxfitfraction*nf),range(1));
      fitrange_b = fitb_start:ix;
      s.pfx_b = polyfit(app.filedata.x(fitrange_b),app.filedata.f(fitrange_b),1);
      % fitting range after rip/zip
      fita_stop = min(ix+2*app.par.ripsteps+round(app.par.maxfitfraction*nf),range(end));
      fitrange_a = (ix +2*app.par.ripsteps):fita_stop;
      s.pfx_a = polyfit(app.filedata.x(fitrange_a),app.filedata.f(fitrange_a),1);
    end
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, riplabels)
      app.UIFigure.Name = 'Rip analysis';
      % app.System_Inert.Items = app.Material_list;
      % app.System_Reactive.Items = app.Material_list;
      % app.System_Reactive.Value = 'Water';
      % drawnow(); pause(.05)
      % app.UIFigure.WindowState='maximized';
      app.par = parameter_struct;
      app.settings = [];
      app.UIAxes2.Visible = "off";  % Traces figure

      if exist("RipAnalysis_settings.mat",'file')
        load RipAnalysis_settings appsettings
        app.settings = appsettings;
        if ~isfield( appsettings,'Datafolder')
          return
        end
        if exist(appsettings.Datafolder,"dir")
          app.Datafolder =  strrep(appsettings.Datafolder,'\','/');
          app.DatafolderTextArea.Value = app.Datafolder;
        else
          return
        end

        if ~isfield( appsettings,'Datafile')
          return
        end
        if exist(fullfile(appsettings.Datafolder,appsettings.Datafile),"file")
          app.Datafile =  appsettings.Datafile;
          app.DatafileTextArea.Value = app.Datafile;
        else
          return
        end

        if ~isfield( appsettings,'Outputfolder')
          return
        end
        app.Outputfolder = appsettings.Outputfolder;
        if exist(app.Outputfolder,"dir")
          app.OutputfolderTextArea.Value = app.Outputfolder;
          % else
          %   return
        end

        read_experiment(app);
        if isempty(app.traces.pull)
          uialert(app.UIFigure,'No episodes found in file','Bad file')
          return
        end
        app.InspectripzipButton.Enable = "on";
      end
    end

    % Button pushed function: SelectfolderButton
    function SelectfolderButtonPushed(app, event)
      folder = uigetdir(app.Datafolder);
      if folder == 0
        return   % User pressed "Cancel"
      end
      folder = strrep(folder,'\','/');  % Mac/Unix format
      app.Datafolder = folder;
      app.settings.Datafolder = folder;
      appsettings = app.settings;
      save RipAnalysis_settings appsettings
      app.DatafolderTextArea.Value = app.Datafolder;
    end

    % Button pushed function: SelectfileButton
    function SelectfileButtonPushed(app, event)
      % select_datafile(app,event);
      [file,path] = uigetfile(strcat(app.settings.Datafolder,"/*.txt"));
      if file == 0
        return  % User pressed "Cancel"
      end 
      % Ensure path is a character array with Unix/Mac delimiters (/)
      path = char(path);
      path = strrep(path,'\','/');  
      % Find element (from end) where path and app.atafolder differ
      k = strfind(flip(path),flip(app.Datafolder));
      if isempty(k)
        msgbox('To select a file from a different data folder, Chage the Data Dolder first')
        return
      end
      shortname = strcat(path(end-k+3:end),file); 
      file = shortname;
      app.Datafile = file;
      app.settings.Datafile = file;      % For storing in RipAnalysis_settings
      app.DatafileTextArea.Value = app.Datafile; % Show name in app
      appsettings = app.settings;
      save RipAnalysis_settings appsettings
      cla(app.UIAxes);
      % app.Datafolder = strrep(path,'\','/');  % Ensure Unix format
      % app.settings.Datafolder = path;  % Not sure if this is needed
      % app.Datafile = file;
      % app.settings.Datafile = file;
      % appsettings = app.settings;
      % app.DatafolderTextArea.Value = app.Datafolder; % Show name in app
      
      read_experiment(app);

      if isempty(app.traces.pull)
        uialert(app.UIFigure,'No rips found in file','Bad file')
        return
      end
      plot_file(app);
    end

    % Button pushed function: RestoreviewButton
    function RestoreviewButtonPushed(app, event)
      app.RemoveripszipsButton.Visible = "on";
      app.SaveButton.Visible = "on";
      app.UIAxes.Visible = "on";
      app.RecallsavedchangesButton.Visible = "on";
      app.yaxisButtonGroup.Visible = "on";
      app.AddripzipButton.Visible = "on";
      app.InspectripzipButton.Visible = "on";
      app.SelectintervaloptionalLabel.Visible = "on";
      app.EndButton.Visible = "on";
      app.StartButton.Visible = "on";

      if numel(app.h)>2 & isfield(app.h(3),'legend')
        delete(app.h(3).legend)
      end
      cla(app.UIAxes2);
      app.UIAxes2.Visible = "off";
      app.UIAxes2.Toolbar.Visible = "off";
      app.UIAxes.Visible = "on";
      axtoolbar(app.UIAxes2);  % remove axis toolbar
      axtoolbar(app.UIAxes,{'default'});
      plot_file(app);
    end

    % Button pushed function: RemoveripszipsButton
    function RemoveripszipsButtonPushed(app, event)
      [localrips,localzips,visiblerips,visiblezips] = ...
        selectrip(app,'Multiple');
      rips = visiblerips(localrips);
      zips = visiblezips(localzips);
      txt = '';
      if ~isempty(localrips)
        txt = 'rips ';
      end
      for rno = 1:numel(localrips)
        txt = [txt, num2str(localrips(rno)), ' '];
      end
      if ~isempty(txt) & ~isempty(zips)
        txt = [txt,'and'];
      end
      if ~isempty(localzips)
        txt = [txt,' zips '];
      end
      for rno = 1:numel(localzips)
        txt = [txt, num2str(localzips(rno)), ' '];
      end
      quest = ['Do you really want to remove ',txt,'?'];
      answer = questdlg(quest);

      if answer == "Yes"
        % Delete trace structs
        for i = 1:numel(rips)
          app.traces.pull(visiblerips(localrips(i))) = [];
        end
        for i = 1:numel(localzips)
          app.traces.relax(visiblezips(localzips(i))) = [];
        end
        cla(app.UIAxes);
        plot_file(app);
      end
    end

    % Button pushed function: InspectripzipButton
    function InspectripzipButtonPushed(app, event)
      % Select a rip or zip event and display an x vs f trace graph with deltax
      % First hide the rip inspection axes and show the file plot axes
      % so the user can select the relevant rip or zip
      cla(app.UIAxes2);
      app.UIAxes2.Visible = "off";
      axtoolbar(app.UIAxes2);  % Hide axes toolbar
      app.UIAxes.Visible = "on";
      axtoolbar(app.UIAxes,'default');  % Show axes toolbar
      xl = app.UIAxes.XLim;
      yl = app.UIAxes.YLim;
      plot_file(app);
      app.UIAxes.XLim = xl;
      app.UIAxes.YLim = yl;
      [localrips,localzips,visiblerips,visiblezips] = ...
        selectrip(app,'Single');

      % Now clean up the GUI by hiding irrelevnt buttons etc.
      cla(app.UIAxes);
      app.RemoveripszipsButton.Visible = "off";
      app.SaveButton.Visible = "off";
      app.RemoveripszipsButton.Visible = "off";
      app.InspectripzipButton.Visible = "off";
      app.AddripzipButton.Visible = "off";
      app.UIAxes.Visible = "off";
      app.RecallsavedchangesButton.Visible = "off";
      app.yaxisButtonGroup.Visible = "off";
      app.xaxisButtonGroup.Visible = "off";
      app.SelectintervaloptionalLabel.Visible = "off";
      app.EndButton.Visible = "off";
      app.StartButton.Visible = "off";
      axtoolbar(app.UIAxes)
      axtoolbar(app.UIAxes2,"default");

      app.UIAxes2.Visible = "on";
      if isempty(localzips)
        ripno = visiblerips(localrips);
        s = app.traces.pull(ripno);
        [s,r] = nexttrace(app,s);
        % Build title:
        ttl = char(shorten_filename(s.file,2));
        if isfield(s,'time')
          ttl = [ttl,sprintf('  Rip time: %.1f',s.time)];
        end
        if isfield(r,'time')
          ttl = [ttl,sprintf('  Zip time: %.1f',r.time)];
        end
      else
        zipno = visiblezips(localzips);
        r = app.traces.relax(zipno);
        [r,s] = nexttrace(app,r);
        % Build title:
        ttl = char(shorten_filename(r.file,2));
        if isfield(r,'time')
          ttl = [ttl,sprintf('  Zip time: %.1f',r.time)];
        end
        if isfield(s,'time')
          ttl = [ttl,sprintf('  Rip time: %.1f',s.time)];
        end
      end
      xlim(app.UIAxes2,[min(s.x(1),r.x(1)),max(s.x(end),r.x(end))]);
      ylim(app.UIAxes2,[min(s.f(1),r.f(end)),max(s.f(end),r.f(1))]);
      app.h = plot_trace(app.UIAxes2,s,r);
      legtxt = {};
      graph_h = [];
      if isfield(s,'deltax')
        legtxt = [legtxt,sprintf('Rip Δx = %3.1fnm',s.deltax)];
        graph_h = [graph_h,app.h(1).Graph];
      end
      if isfield(r,'deltax')
        legtxt = [legtxt,sprintf('Zip Δx = %3.1fnm',r.deltax)];
        graph_h = [graph_h,app.h(2).Graph];
      end
      app.h(3).legend = legend(graph_h,legtxt,Location='northwest');
      app.UIAxes2.XLim = [min(s.x),max(s.x)];
      title(app.UIAxes2,ttl)
    end

    % Button pushed function: RecallsavedchangesButton
    function RecallsavedchangesButtonPushed(app, event)

      parts = split(string(app.Datafolder),'/');
      outputfile = fullfile(app.Outputfolder, ...
        strrep(app.Datafile,".txt",".mat"));
      if ~exist(outputfile,'file')
        msgbox(sprintf("Results file %s not found",outputfile))
        return
      end
      load(outputfile,'Trip','Tzip','limits');

      app.traces.pull = table2tracestruct(app,Trip,0,1);
      % Add new rips from table:
      for row = 1:height(Trip)
        app.traces.pull(row) = table2tracestruct(app,Trip,row,1);
      end
      app.traces.relax = table2tracestruct(app,Tzip,0,-1);
      for row = 1:height(Tzip)
        app.traces.relax(row) = table2tracestruct(app,Tzip,row,-1);
      end
      app.filedata.limits = limits;
      plot_file(app)
    end

    % Button pushed function: SaveButton
    function SaveButtonPushed(app, event)
      % msgbox('The results will be saved as Matlab tables Trip and Tzip')
      if ~isfield(app.settings,'Outputfolder')
        waitfor(msgbox('Please select a folder for output files'));
        folder = strrep(uigetdir,'\','/');  % Convert to Unix format
        if folder == 0
          return   % User pressed "Cancel"
        end
        app.Outputfolder = folder;
        app.settings.Outputfolder = folder;
        appsettings = app.settings;
        save RipAnalysis_settings appsettings
      end
      Trip = create_table(app.traces.pull);  % Rip table
      Tzip = create_table(app.traces.relax);  % Zip table
      parts = strsplit(string(app.Datafile),"/");
      nparts = numel(parts);
      folder = string(app.Outputfolder);
      if nparts > 1
        for i = 1:nparts-1
          folder = fullfile(folder,parts(i));
          if ~exist(folder,"dir")
            mkdir(folder);
          end
        end
      end
      matfile = strrep(app.Datafile,'.txt','.mat');
      excelfile = strrep(app.Datafile,'.txt','.xlsx');
      limits = app.filedata.limits;  % Axis limits
      save(fullfile(app.Outputfolder,matfile),'Trip','Tzip','limits');
      try
        writetable(Trip,fullfile(app.Outputfolder,excelfile),'Sheet','Rips');
        writetable(Tzip,fullfile(app.Outputfolder,excelfile),'Sheet','Zips');
        msgbox(sprintf("Results tables saved to %s",matfile))
      catch
        msgbox(sprintf('Please close %s and try again',excelfile));
      end      
    end

    % Value changed function: DatafolderTextArea
    function DatafolderTextAreaValueChanged(app, event)
      folder = app.DatafolderTextArea.Value;
      if iscell(folder)
        folder = folder{1};
      end
      app.settings.Datafolder = folder;
      appsettings = app.settings;
      save RipAnalysis_settings appsettings
    end

    % Value changed function: DatafileTextArea
    function DatafileTextAreaValueChanged(app, event)
      file = app.DatafileTextArea.Value;
      if iscell(file)
        file = file{1};
      end
      app.settings.Datafile = file;
      app.Datafile = file;
      appsettings = app.settings;
      save RipAnalysis_settings appsettings
      read_experiment(app);
      plot_file(app);
    end

    % Button pushed function: SelectfolderButton_2
    function SelectfolderButton_2Pushed(app, event)
      folder = uigetdir(app.Outputfolder);
      if folder == 0
        return   % User pressed "Cancel"
      end
      app.Outputfolder = strrep(folder,'\','/');
      app.settings.Outputfolder = folder;
      appsettings = app.settings;
      save RipAnalysis_settings appsettings
      app.OutputfolderTextArea.Value = app.Outputfolder;
    end

    % Selection changed function: xaxisButtonGroup
    function xaxisButtonGroupSelectionChanged(app, event)
      plot_file(app);
    end

    % Button pushed function: StartButton
    function StartButtonPushed(app, event)
      setlimit(app,'Start');
    end

    % Button pushed function: EndButton
    function EndButtonPushed(app, event)
      setlimit(app,'End')
    end

    % Button pushed function: AddripzipButton
    function AddripzipButtonPushed(app, event)
      % ***********************************************
      % Cast magic spell to make ginput crosshairs apppear in App window:
      fhv = app.UIFigure.HandleVisibility;        % Current state
      app.UIFigure.HandleVisibility = 'callback'; % Temporarily make UIFigure handle visible outside app
      set(0, 'CurrentFigure', app.UIFigure);
      answer = 'No';
      while strcmp(answer,'No')
        [answer,new,sgn] = tryrip(app);
      end
      app.UIFigure.HandleVisibility = fhv;
      % Magic spell lifted
      % ***********************************************
      if ~strcmp(answer,'Yes')  % User selected 'Cancel'
        return
      end
      % New rip/zip accepted. Insert in app.traces
      if sgn > 0  % Pull trace
        if isempty(app.traces.pull)
          app.traces.pull = new;
        else
          times = vertcat(app.traces.pull.time);
          if new.time < times(1)
            app.traces.pull = [new;app.traces.pull];
          else
            index = find(times>new.time,1);
            if isempty(index)  % add new at end
              app.traces.pull = [app.traces.pull;new];
            else
              ntraces = length(times);
              app.traces.pull(index+1:ntraces+1) = app.traces.pull(index:ntraces);
              app.traces.pull(index) = new;
            end
          end
        end
      else
        if isempty(app.traces.relax)
          app.traces.relax = new;
        else
          times = vertcat(app.traces.relax.time);
          if new.time < times(1)
            app.traces.relax = [new;app.traces.relax];
          else
            index = find(times>new.time,1);
            if isempty(index)  % add new at end
              app.traces.relax = [app.traces.relax;new];
            else
              ntraces = length(times);
              app.traces.relax(index+1:ntraces+1) = app.traces.relax(index:ntraces);
              app.traces.relax(index) = new;
            end
          end
        end
      end
      plot_file(app);
    end

    % Selection changed function: yaxisButtonGroup
    function yaxisButtonGroupSelectionChanged2(app, event)
      plot_file(app);
    end

    % Value changed function: OutputfolderTextArea
    function OutputfolderTextAreaValueChanged(app, event)
      folder = app.OutputfolderTextArea.Value;
      folder = folder{1};
      app.settings.Outputfolder = folder;
      app.Outputfolder = folder;
      appsettings = app.settings;
      save RipAnalysis_settings appsettings
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.UIAxes = uiaxes(app.UIFigure);
      title(app.UIAxes, 'Title')
      xlabel(app.UIAxes, 'X')
      ylabel(app.UIAxes, 'Y')
      zlabel(app.UIAxes, 'Z')
      app.UIAxes2 = uiaxes(app.UIFigure);
      title(app.UIAxes2, 'Title')
      xlabel(app.UIAxes2, 'X')
      ylabel(app.UIAxes2, 'Y')
      zlabel(app.UIAxes2, 'Z')
      app.SelectfolderButton = uibutton(app.UIFigure, 'push');
      app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
      app.SelectfolderButton.Text = 'Select folder';
      app.SelectfileButton = uibutton(app.UIFigure, 'push');
      app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
      app.SelectfileButton.Text = 'Select file';
      app.RestoreviewButton = uibutton(app.UIFigure, 'push');
      app.RestoreviewButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreviewButtonPushed, true);
      app.RestoreviewButton.Tag = 'cycle';
      app.RestoreviewButton.Tooltip = {'Show f(t) for full file'};
      app.RestoreviewButton.Text = 'Restore view';
      app.DatafolderTextAreaLabel = uilabel(app.UIFigure);
      app.DatafolderTextAreaLabel.Text = 'Data folder';
      app.DatafolderTextArea = uitextarea(app.UIFigure);
      app.DatafolderTextArea.ValueChangedFcn = createCallbackFcn(app, @DatafolderTextAreaValueChanged, true);
      app.DatafileTextAreaLabel = uilabel(app.UIFigure);
      app.DatafileTextAreaLabel.Text = 'Data file';
      app.DatafileTextArea = uitextarea(app.UIFigure);
      app.DatafileTextArea.ValueChangedFcn = createCallbackFcn(app, @DatafileTextAreaValueChanged, true);
      app.InspectripzipButton = uibutton(app.UIFigure, 'push');
      app.InspectripzipButton.ButtonPushedFcn = createCallbackFcn(app, @InspectripzipButtonPushed, true);
      app.InspectripzipButton.Tooltip = {'Select rip ot zip. Shows f(x) for selected and following trace'};
      app.InspectripzipButton.Text = 'Inspect rip/zip';
      app.RemoveripszipsButton = uibutton(app.UIFigure, 'push');
      app.RemoveripszipsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveripszipsButtonPushed, true);
      app.RemoveripszipsButton.Text = 'Remove rips/zips';
      app.RecallsavedchangesButton = uibutton(app.UIFigure, 'push');
      app.RecallsavedchangesButton.ButtonPushedFcn = createCallbackFcn(app, @RecallsavedchangesButtonPushed, true);
      app.RecallsavedchangesButton.Tooltip = {'Read  data from output tables Trop and Tzip'};
      app.RecallsavedchangesButton.Text = 'Recall saved changes';
      app.SaveButton = uibutton(app.UIFigure, 'push');
      app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
      app.SaveButton.Tooltip = {'Save resultstables Trip and Tzip to file'};
      app.SaveButton.Text = 'Save';
      app.SelectfolderButton_2 = uibutton(app.UIFigure, 'push');
      app.SelectfolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButton_2Pushed, true);
      app.SelectfolderButton_2.Text = 'Select folder';
      app.OutputfolderTextAreaLabel = uilabel(app.UIFigure);
      app.OutputfolderTextAreaLabel.Text = 'Output folder';
      app.OutputfolderTextArea = uitextarea(app.UIFigure);
      app.OutputfolderTextArea.ValueChangedFcn = createCallbackFcn(app, @OutputfolderTextAreaValueChanged, true);
      app.AddripzipButton = uibutton(app.UIFigure, 'push');
      app.AddripzipButton.ButtonPushedFcn = createCallbackFcn(app, @AddripzipButtonPushed, true);
      app.AddripzipButton.Tooltip = {'Not implemented'};
      app.AddripzipButton.Text = 'Add rip/zip';
      app.xaxisButtonGroup = uibuttongroup(app.UIFigure);
      app.xaxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @xaxisButtonGroupSelectionChanged, true);
      app.xaxisButtonGroup.Title = 'x axis';
      app.TimesButton = uiradiobutton(app.xaxisButtonGroup);
      app.TimesButton.Text = 'Time (s)';
      app.TimesButton.Value = true;
      app.RecordButton = uiradiobutton(app.xaxisButtonGroup);
      app.RecordButton.Text = 'Record';
      app.StartButton = uibutton(app.UIFigure, 'push');
      app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
      app.StartButton.Tooltip = {'Default: Beginning'};
      app.StartButton.Text = 'Start';
      app.EndButton = uibutton(app.UIFigure, 'push');
      app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
      app.EndButton.Tooltip = {'Default: end of file'};
      app.EndButton.Text = 'End';
      app.SelectintervaloptionalLabel = uilabel(app.UIFigure);
      app.SelectintervaloptionalLabel.Text = 'Select interval (optional)';
      app.yaxisButtonGroup = uibuttongroup(app.UIFigure);
      app.yaxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @yaxisButtonGroupSelectionChanged2, true);
      app.yaxisButtonGroup.Title = 'y axis';
      app.fButton = uiradiobutton(app.yaxisButtonGroup);
      app.fButton.Text = 'f';
      app.fButton.Value = true;
      app.xButton = uiradiobutton(app.yaxisButtonGroup);
      app.xButton.Text = 'x';
      app.TButton = uiradiobutton(app.yaxisButtonGroup);
      app.TButton.Text = 'T';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = RipAnalysis(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end